classdef binocularMRI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		BinocularMRIUIFigure            matlab.ui.Figure
		BinocularMRIStimuluatorV101Panel  matlab.ui.container.Panel
		RepeatsEditField                matlab.ui.control.NumericEditField
		RepeatsEditFieldLabel           matlab.ui.control.Label
		StimInterstimTimesEditField     matlab.ui.control.EditField
		StimInterstimTimesEditFieldLabel  matlab.ui.control.Label
		SFModEditField                  matlab.ui.control.NumericEditField
		SFModEditFieldLabel             matlab.ui.control.Label
		AngleModEditField               matlab.ui.control.NumericEditField
		AngleModEditFieldLabel          matlab.ui.control.Label
		FlashRateEditField              matlab.ui.control.NumericEditField
		FlashRateEditFieldLabel         matlab.ui.control.Label
		SpatialFrequencyEditField       matlab.ui.control.NumericEditField
		SpatialFrequencyEditFieldLabel  matlab.ui.control.Label
		StimulusListBox                 matlab.ui.control.ListBox
		StimulusListBoxLabel            matlab.ui.control.Label
		RightRGBEditField               matlab.ui.control.EditField
		RightRGBEditFieldLabel          matlab.ui.control.Label
		LeftRGBEditField                matlab.ui.control.EditField
		LeftRGBEditFieldLabel           matlab.ui.control.Label
		BackgroundEditField             matlab.ui.control.EditField
		BackgroundEditFieldLabel        matlab.ui.control.Label
		StereoModeEditField             matlab.ui.control.NumericEditField
		StereoModeEditFieldLabel        matlab.ui.control.Label
		QuitButton                      matlab.ui.control.Button
		RunButton                       matlab.ui.control.Button
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: RunButton
		function RunButtonPushed(app, event)
			
			in.stereoMode = app.StereoModeEditField.Value;
			in.bg = str2num(app.BackgroundEditField.Value);
			in.left = str2num(app.LeftRGBEditField.Value);
			in.right = str2num(app.RightRGBEditField.Value);
			in.type = app.StimulusListBox.Value;
			in.sf = app.SpatialFrequencyEditField.Value;
			in.flash = app.FlashRateEditField.Value;
			in.anglemod = app.AngleModEditField.Value;
			in.sfmod = app.SFModEditField.Value;
			in.repeats =  app.RepeatsEditField.Value;
			in.times = str2num(app.StimInterstimTimesEditField.Value);

			binocularStimulation(in);
		end

		% Button pushed function: QuitButton
		function QuitButtonPushed(app, event)
			delete(app);
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.BinocularMRIUIFigure = uifigure('Visible', 'off');
			app.BinocularMRIUIFigure.Name = 'Binocular MRI';
			app.BinocularMRIStimuluatorV101Panel = uipanel(app.BinocularMRIUIFigure);
			app.BinocularMRIStimuluatorV101Panel.Title = 'Binocular MRI Stimuluator V1.01';
			app.RunButton = uibutton(app.BinocularMRIStimuluatorV101Panel, 'push');
			app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
			app.RunButton.Text = 'Run!';
			app.QuitButton = uibutton(app.BinocularMRIStimuluatorV101Panel, 'push');
			app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
			app.QuitButton.Text = 'Quit';
			app.StereoModeEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.StereoModeEditFieldLabel.Text = 'Stereo Mode';
			app.StereoModeEditField = uieditfield(app.BinocularMRIStimuluatorV101Panel, 'numeric');
			app.StereoModeEditField.Value = 8;
			app.BackgroundEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.BackgroundEditFieldLabel.Text = 'Background';
			app.BackgroundEditField = uieditfield(app.BinocularMRIStimuluatorV101Panel, 'text');
			app.BackgroundEditField.Value = '0  0  0';
			app.LeftRGBEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.LeftRGBEditFieldLabel.Text = 'Left RGB';
			app.LeftRGBEditField = uieditfield(app.BinocularMRIStimuluatorV101Panel, 'text');
			app.LeftRGBEditField.Value = '0.9  0  0';
			app.RightRGBEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.RightRGBEditFieldLabel.Text = 'Right RGB';
			app.RightRGBEditField = uieditfield(app.BinocularMRIStimuluatorV101Panel, 'text');
			app.RightRGBEditField.Value = '0  0.3  1';
			app.StimulusListBoxLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.StimulusListBoxLabel.Text = 'Stimulus';
			app.StimulusListBox = uilistbox(app.BinocularMRIStimuluatorV101Panel);
			app.StimulusListBox.Items = {'Checkerboard', 'Spiral', 'Radial', 'Circular'};
			app.StimulusListBox.Value = 'Checkerboard';
			app.SpatialFrequencyEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.SpatialFrequencyEditFieldLabel.Text = 'Spatial Frequency';
			app.SpatialFrequencyEditField = uieditfield(app.BinocularMRIStimuluatorV101Panel, 'numeric');
			app.SpatialFrequencyEditField.Value = 0.5;
			app.FlashRateEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.FlashRateEditFieldLabel.Text = 'Flash Rate';
			app.FlashRateEditField = uieditfield(app.BinocularMRIStimuluatorV101Panel, 'numeric');
			app.AngleModEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.AngleModEditFieldLabel.Text = 'Angle Mod';
			app.AngleModEditField = uieditfield(app.BinocularMRIStimuluatorV101Panel, 'numeric');
			app.SFModEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.SFModEditFieldLabel.Text = 'SF Mod';
			app.SFModEditField = uieditfield(app.BinocularMRIStimuluatorV101Panel, 'numeric');
			app.StimInterstimTimesEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.StimInterstimTimesEditFieldLabel.Text = 'Stim / Inter-stim Time (s)';
			app.StimInterstimTimesEditField = uieditfield(app.BinocularMRIStimuluatorV101Panel, 'text');
			app.StimInterstimTimesEditField.Value = '14 14';
			app.RepeatsEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV101Panel);
			app.RepeatsEditFieldLabel.Text = 'Repeats';
			app.RepeatsEditField = uieditfield(app.BinocularMRIStimuluatorV101Panel, 'numeric');
			app.RepeatsEditField.Value = 10;
			% Show the figure after all components are created
			app.BinocularMRIUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = binocularMRI

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.BinocularMRIUIFigure)
			else

				% Focus the running singleton app
				figure(runningApp.BinocularMRIUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.BinocularMRIUIFigure)
		end
	end
end